classdef HistMatchingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OKButton                  matlab.ui.control.Button
        LoadReferenceImageButton  matlab.ui.control.Button
        ImageAxesRef              matlab.ui.control.UIAxes
        ImageAxesInput            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        RefImage
        HistData
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            try
                app.RefImage = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            
            imagesc(app.ImageAxesRef, app.RefImage, [0,255]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, input_image)
            app.CallingApp = mainapp;

            % Configure image axes
            app.ImageAxesInput.Visible = 'off';
            app.ImageAxesInput.Colormap = gray(256);
            axis(app.ImageAxesInput, 'image');
            app.ImageAxesRef.Visible = 'off';
            app.ImageAxesRef.Colormap = gray(256);
            axis(app.ImageAxesRef, 'image');

            imagesc(app.ImageAxesInput, input_image, [0,255]);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            updateRef(app.CallingApp, app.RefImage)
            
            app.CallingApp.HistMatchingButton.Enable = "on";
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.HistMatchingButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxesInput = uiaxes(app.UIFigure);
            app.ImageAxesInput.XTick = [];
            app.ImageAxesInput.XTickLabel = {'[ ]'};
            app.ImageAxesInput.YTick = [];
            app.ImageAxesRef = uiaxes(app.UIFigure);
            app.ImageAxesRef.XTick = [];
            app.ImageAxesRef.XTickLabel = {'[ ]'};
            app.ImageAxesRef.YTick = [];
            app.LoadReferenceImageButton = uibutton(app.UIFigure, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistMatchingWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end