classdef HistOneRGBWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GreenAxesInput  matlab.ui.control.UIAxes
        BlueAxesInput   matlab.ui.control.UIAxes
        RedAxesInput    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, hist)
            app.CallingApp = mainapp;

            if hist.channel == 3
                bar(app.RedAxesInput,hist.data(:,1));
                bar(app.GreenAxesInput,hist.data(:,2));
                bar(app.BlueAxesInput,hist.data(:,3));
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.HistogramButton.Enable = "on";

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RedAxesInput = uiaxes(app.UIFigure);
            title(app.RedAxesInput, 'Red')
            xlabel(app.RedAxesInput, 'Intensity')
            ylabel(app.RedAxesInput, 'Pixels')
            app.RedAxesInput.XLim = [0 255];
            app.RedAxesInput.XTick = [0 128 255];
            app.BlueAxesInput = uiaxes(app.UIFigure);
            title(app.BlueAxesInput, 'Blue')
            xlabel(app.BlueAxesInput, 'Intensity')
            ylabel(app.BlueAxesInput, 'Pixels')
            app.BlueAxesInput.XLim = [0 255];
            app.BlueAxesInput.XTick = [0 128 255];
            app.GreenAxesInput = uiaxes(app.UIFigure);
            title(app.GreenAxesInput, 'Green')
            xlabel(app.GreenAxesInput, 'Intensity')
            ylabel(app.GreenAxesInput, 'Pixels')
            app.GreenAxesInput.XLim = [0 255];
            app.GreenAxesInput.XTick = [0 128 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistOneRGBWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end