classdef HistRefGrayWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GrayAxesRef     matlab.ui.control.UIAxes
        GrayAxesOutput  matlab.ui.control.UIAxes
        GrayAxesInput   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, hist, refhist)
            app.CallingApp = mainapp;

            if hist.input.channel == 1
                bar(app.GrayAxesInput,hist.input.data(:,1));
            end

            if refhist.channel == 1
                bar(app.GrayAxesRef,refhist.data(:,1));
            end

            if hist.output.channel == 1
                bar(app.GrayAxesOutput,hist.output.data(:,1));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.HistogramButton.Enable = "on";

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GrayAxesInput = uiaxes(app.UIFigure);
            title(app.GrayAxesInput, 'Gray')
            xlabel(app.GrayAxesInput, 'Intensity')
            ylabel(app.GrayAxesInput, 'Pixels')
            app.GrayAxesInput.XLim = [0 255];
            app.GrayAxesInput.XTick = [0 128 255];
            app.GrayAxesOutput = uiaxes(app.UIFigure);
            title(app.GrayAxesOutput, 'Gray')
            xlabel(app.GrayAxesOutput, 'Intensity')
            ylabel(app.GrayAxesOutput, 'Pixels')
            app.GrayAxesOutput.XLim = [0 255];
            app.GrayAxesOutput.XTick = [0 128 255];
            app.GrayAxesRef = uiaxes(app.UIFigure);
            title(app.GrayAxesRef, 'Gray')
            xlabel(app.GrayAxesRef, 'Intensity')
            ylabel(app.GrayAxesRef, 'Pixels')
            app.GrayAxesRef.XLim = [0 255];
            app.GrayAxesRef.XTick = [0 128 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistRefGrayWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end