classdef HistRefRGBWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GreenAxesRef     matlab.ui.control.UIAxes
        BlueAxesRef      matlab.ui.control.UIAxes
        RedAxesRef       matlab.ui.control.UIAxes
        GreenAxesOutput  matlab.ui.control.UIAxes
        BlueAxesOutput   matlab.ui.control.UIAxes
        RedAxesOutput    matlab.ui.control.UIAxes
        GreenAxesInput   matlab.ui.control.UIAxes
        BlueAxesInput    matlab.ui.control.UIAxes
        RedAxesInput     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, hist, refhist)
            app.CallingApp = mainapp;

            if hist.input.channel == 3
                bar(app.RedAxesInput,hist.input.data(:,1));
                bar(app.GreenAxesInput,hist.input.data(:,2));
                bar(app.BlueAxesInput,hist.input.data(:,3));
            end

            if refhist.channel == 3
                bar(app.RedAxesRef,refhist.data(:,1));
                bar(app.GreenAxesRef,refhist.data(:,2));
                bar(app.BlueAxesRef,refhist.data(:,3));
            end

            if hist.output.channel == 3
                bar(app.RedAxesOutput,hist.output.data(:,1));
                bar(app.GreenAxesOutput,hist.output.data(:,2));
                bar(app.BlueAxesOutput,hist.output.data(:,3));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.HistogramButton.Enable = "on";

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RedAxesInput = uiaxes(app.UIFigure);
            title(app.RedAxesInput, 'Red')
            xlabel(app.RedAxesInput, 'Intensity')
            ylabel(app.RedAxesInput, 'Pixels')
            app.RedAxesInput.XLim = [0 255];
            app.RedAxesInput.XTick = [0 128 255];
            app.BlueAxesInput = uiaxes(app.UIFigure);
            title(app.BlueAxesInput, 'Blue')
            xlabel(app.BlueAxesInput, 'Intensity')
            ylabel(app.BlueAxesInput, 'Pixels')
            app.BlueAxesInput.XLim = [0 255];
            app.BlueAxesInput.XTick = [0 128 255];
            app.GreenAxesInput = uiaxes(app.UIFigure);
            title(app.GreenAxesInput, 'Green')
            xlabel(app.GreenAxesInput, 'Intensity')
            ylabel(app.GreenAxesInput, 'Pixels')
            app.GreenAxesInput.XLim = [0 255];
            app.GreenAxesInput.XTick = [0 128 255];
            app.RedAxesOutput = uiaxes(app.UIFigure);
            title(app.RedAxesOutput, 'Red')
            xlabel(app.RedAxesOutput, 'Intensity')
            ylabel(app.RedAxesOutput, 'Pixels')
            app.RedAxesOutput.XLim = [0 255];
            app.RedAxesOutput.XTick = [0 128 255];
            app.BlueAxesOutput = uiaxes(app.UIFigure);
            title(app.BlueAxesOutput, 'Blue')
            xlabel(app.BlueAxesOutput, 'Intensity')
            ylabel(app.BlueAxesOutput, 'Pixels')
            app.BlueAxesOutput.XLim = [0 255];
            app.BlueAxesOutput.XTick = [0 128 255];
            app.GreenAxesOutput = uiaxes(app.UIFigure);
            title(app.GreenAxesOutput, 'Green')
            xlabel(app.GreenAxesOutput, 'Intensity')
            ylabel(app.GreenAxesOutput, 'Pixels')
            app.GreenAxesOutput.XLim = [0 255];
            app.GreenAxesOutput.XTick = [0 128 255];
            app.RedAxesRef = uiaxes(app.UIFigure);
            title(app.RedAxesRef, 'Red')
            xlabel(app.RedAxesRef, 'Intensity')
            ylabel(app.RedAxesRef, 'Pixels')
            app.RedAxesRef.XLim = [0 255];
            app.RedAxesRef.XTick = [0 128 255];
            app.BlueAxesRef = uiaxes(app.UIFigure);
            title(app.BlueAxesRef, 'Blue')
            xlabel(app.BlueAxesRef, 'Intensity')
            ylabel(app.BlueAxesRef, 'Pixels')
            app.BlueAxesRef.XLim = [0 255];
            app.BlueAxesRef.XTick = [0 128 255];
            app.GreenAxesRef = uiaxes(app.UIFigure);
            title(app.GreenAxesRef, 'Green')
            xlabel(app.GreenAxesRef, 'Intensity')
            ylabel(app.GreenAxesRef, 'Pixels')
            app.GreenAxesRef.XLim = [0 255];
            app.GreenAxesRef.XTick = [0 128 255];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistRefRGBWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end