classdef ImageProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HistMatchingButton         matlab.ui.control.Button
        HistEqualizationButton     matlab.ui.control.Button
        ContrastEnhancementButton  matlab.ui.control.Button
        HistogramButton            matlab.ui.control.Button
        LoadImageButton            matlab.ui.control.Button
        ImageAxesRef               matlab.ui.control.UIAxes
        ImageAxesOutput_2          matlab.ui.control.UIAxes
        ImageAxesInput_2           matlab.ui.control.UIAxes
        ImageAxesOutput            matlab.ui.control.UIAxes
        ImageAxesInput             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        HistApp
        HistData
        ImageData
        RefData
    end

    methods (Access = public)
        function updateRef(app, refimage)
            app.RefData.status = true;
            app.RefData.image = refimage;

            [~,~,channel] = size(app.RefData.image);
            app.RefData.hist.channel = channel;
            for i = 1:channel
                app.RefData.hist.data(:,i) = chist(app.RefData.image(:,:,i));
            end
            
            app.ImageData.output = hist_specification(app.ImageData.input, app.RefData.image);

            [~,~,channel] = size(app.ImageData.output);
            app.HistData.output.channel = channel;
            for i = 1:channel
                app.HistData.output.data(:,i) = chist(app.ImageData.output(:,:,i));
            end
            
            displayImages(app);
        end
    end

    methods (Access = private)

        function updateimage(app,imagefile)
            app.RefData.status = false;
            
            try
                app.ImageData.input = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
      
            [~,~,channel] = size(app.ImageData.input);
            
            for i = 1:channel
                app.HistData.input.data(:,i) = chist(app.ImageData.input(:,:,i));
            end

            app.HistData.input.channel = channel;

            displayImages(app);

            %{
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxesInput,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxesInput,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            %}
         
        end
        
        function displayImages(app)
            if app.RefData.status == true
                app.ImageAxesInput.Visible = 'off';
                app.ImageAxesOutput.Visible = 'off';
                cla(app.ImageAxesInput);
                cla(app.ImageAxesOutput);
                imagesc(app.ImageAxesInput_2, app.ImageData.input, [0,255])
                imagesc(app.ImageAxesRef, app.RefData.image, [0,255]);
                imagesc(app.ImageAxesOutput_2, app.ImageData.output, [0,255]);
            else
                app.ImageAxesInput_2.Visible = 'off';
                app.ImageAxesRef.Visible = 'off';
                app.ImageAxesOutput_2.Visible = 'off';
                cla(app.ImageAxesInput_2);
                cla(app.ImageAxesRef)
                cla(app.ImageAxesOutput_2)
                imagesc(app.ImageAxesInput, app.ImageData.input, [0,255])
                imagesc(app.ImageAxesOutput, app.ImageData.output, [0,255]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageData.input = [];
            app.ImageData.output = [];
            app.HistData.input.data = [];
            app.HistData.input.channel = 0;
            app.HistData.output.data = [];
            app.HistData.output.channel = 0;
            app.RefData.status = false;

            % Configure image axes
            app.ImageAxesInput.Visible = 'off';
            app.ImageAxesInput.Colormap = gray(256);
            axis(app.ImageAxesInput, 'image');
            app.ImageAxesOutput.Visible = 'off';
            app.ImageAxesOutput.Colormap = gray(256);
            axis(app.ImageAxesOutput, 'image');

            app.ImageAxesInput_2.Visible = 'off';
            app.ImageAxesInput_2.Colormap = gray(256);
            axis(app.ImageAxesInput_2, 'image');
            app.ImageAxesOutput_2.Visible = 'off';
            app.ImageAxesOutput_2.Colormap = gray(256);
            axis(app.ImageAxesOutput_2, 'image');
            app.ImageAxesRef.Visible = 'off';
            app.ImageAxesRef.Colormap = gray(256);
            axis(app.ImageAxesRef, 'image');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            if app.RefData.status == true
                if app.HistData.input.channel == 3 && app.HistData.output.channel == 3
                    app.HistogramButton.Enable = 'off';
                    app.HistApp = HistRefRGBWindow(app, app.HistData, app.RefData.hist);
                elseif app.HistData.input.channel == 1 && app.HistData.output.channel == 1
                    app.HistogramButton.Enable = 'off';
                    app.HistApp = HistRefGrayWindow(app, app.HistData, app.RefData.hist);
                end
            elseif app.RefData.status == false
                if app.HistData.input.channel == 3 && app.HistData.output.channel == 3
                    app.HistogramButton.Enable = 'off';
                    app.HistApp = HistRGBWindow(app, app.HistData);
                elseif app.HistData.input.channel == 1 && app.HistData.output.channel == 1
                    app.HistogramButton.Enable = 'off';
                    app.HistApp = HistGrayWindow(app, app.HistData);
                end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.HistApp)
            delete(app)
        end

        % Button pushed function: HistEqualizationButton
        function HistEqualizationButtonPushed(app, event)
            app.RefData.status = false;
            app.ImageData.output = chisteq(app.ImageData.input);
            
            [~,~,channel] = size(app.ImageData.output);
            app.HistData.output.channel = channel;
            for i = 1:channel
                app.HistData.output.data(:,i) = chist(app.ImageData.output(:,:,i));
            end

            displayImages(app);
        end

        % Button pushed function: ContrastEnhancementButton
        function ContrastEnhancementButtonPushed(app, event)
            app.RefData.status = false;
            app.ImageData.output = contrast_stretching(app.ImageData.input);

            [~,~,channel] = size(app.ImageData.output);
            app.HistData.output.channel = channel;
            for i = 1:channel
                app.HistData.output.data(:,i) = chist(app.ImageData.output(:,:,i));
            end

            displayImages(app);
        end

        % Button pushed function: HistMatchingButton
        function HistMatchingButtonPushed(app, event)
            app.HistMatchingButton.Enable = 'off';
            app.HistApp = HistMatchingWindow(app, app.ImageData.input);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxesInput = uiaxes(app.UIFigure);
            app.ImageAxesInput.XTick = [];
            app.ImageAxesInput.XTickLabel = {'[ ]'};
            app.ImageAxesInput.YTick = [];
            app.ImageAxesOutput = uiaxes(app.UIFigure);
            app.ImageAxesOutput.XTick = [];
            app.ImageAxesOutput.XTickLabel = {'[ ]'};
            app.ImageAxesOutput.YTick = [];
            app.ImageAxesInput_2 = uiaxes(app.UIFigure);
            app.ImageAxesInput_2.XTick = [];
            app.ImageAxesInput_2.XTickLabel = {'[ ]'};
            app.ImageAxesInput_2.YTick = [];
            app.ImageAxesOutput_2 = uiaxes(app.UIFigure);
            app.ImageAxesOutput_2.XTick = [];
            app.ImageAxesOutput_2.XTickLabel = {'[ ]'};
            app.ImageAxesOutput_2.YTick = [];
            app.ImageAxesRef = uiaxes(app.UIFigure);
            app.ImageAxesRef.XTick = [];
            app.ImageAxesRef.XTickLabel = {'[ ]'};
            app.ImageAxesRef.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Show Histogram';
            app.ContrastEnhancementButton = uibutton(app.UIFigure, 'push');
            app.ContrastEnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastEnhancementButtonPushed, true);
            app.ContrastEnhancementButton.Text = 'Contrast Enhancement';
            app.HistEqualizationButton = uibutton(app.UIFigure, 'push');
            app.HistEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistEqualizationButtonPushed, true);
            app.HistEqualizationButton.Text = 'Histogram Equalization';
            app.HistMatchingButton = uibutton(app.UIFigure, 'push');
            app.HistMatchingButton.ButtonPushedFcn = createCallbackFcn(app, @HistMatchingButtonPushed, true);
            app.HistMatchingButton.Text = 'Histogram Matching';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end